classdef KeybotInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Keybot_InterfaceUIFigure   matlab.ui.Figure
        LetterSentTextArea         matlab.ui.control.TextArea
        LetterSentTextAreaLabel    matlab.ui.control.Label
        EndButton                  matlab.ui.control.Button
        TimeLeftsecEditField       matlab.ui.control.EditField
        TimeLeftsecEditFieldLabel  matlab.ui.control.Label
        TexttotypeTextArea         matlab.ui.control.TextArea
        TexttotypeTextAreaLabel    matlab.ui.control.Label
        TypehereTextArea           matlab.ui.control.TextArea
        TypehereTextAreaLabel      matlab.ui.control.Label
        PauseButton                matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        initialTime = 40; % Default
        typingActive = 0; % Toggle to activate typing.
    end

    properties (Access = public)
        timerActive = 0; % Toggle to activate countdown.
        timeLeft = 40; % Time to count down.
        currLetter = "&"; % Letter that should be typed.
        keyCounter = 0; % Keep count of number of keys pressed
        timerStarted = 0;
        matlabFileTimer = 0; % Toggle between file and app timer.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.timerActive = 1;

            % Use this timer if you are not running on a matlab file.
            if app.matlabFileTimer == 0
                interval = 1; % sec
                while app.timeLeft>0
                    pause(interval);
                    app.timeLeft= app.timeLeft-interval;
                    app.TimeLeftsecEditField.Value = num2str(app.timeLeft);
                    if app.timerActive == 0
                        break;
                    end
                end
            end
        
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.timerActive = 0;
            app.timerStarted = 0;
            app.TimeLeftsecEditField.Value = num2str(floor(app.timeLeft));
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.timerActive = 0;
            app.timerStarted = 0;
%             app.timeLeft = app.initialTime;
%             app.TimeLeftsecEditField.Value = num2str(app.initialTime);
            app.timeLeft = 0;
            app.TimeLeftsecEditField.Value = num2str(app.timeLeft);
        end

        % Value changed function: TimeLeftsecEditField
        function TimeLeftsecEditFieldValueChanged(app, event)
            app.timeLeft = str2double(app.TimeLeftsecEditField.Value);
            app.initialTime = app.timeLeft;
        end

        % Value changing function: TypehereTextArea
        function TypehereTextAreaValueChanging(app, event)
            app.typingActive = 1; % Toggle to activate typing
        end

        % Value changed function: TypehereTextArea
        function TypehereTextAreaValueChanged(app, event)
            app.typingActive = 0; % Toggle to activate typing
        end

        % Window key release function: Keybot_InterfaceUIFigure
        function Keybot_InterfaceUIFigureWindowKeyRelease(app, event)
            key = event.Key;

            if app.typingActive == 1
                app.keyCounter = app.keyCounter + 1;
                app.currLetter = key;
                app.LetterSentTextArea.Value = app.currLetter;
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Keybot_InterfaceUIFigure = uifigure('Visible', 'off');
            app.Keybot_InterfaceUIFigure.Name = 'Keybot_Interface';
            app.Keybot_InterfaceUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @Keybot_InterfaceUIFigureWindowKeyRelease, true);
            app.StartButton = uibutton(app.Keybot_InterfaceUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.Keybot_InterfaceUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.TypehereTextAreaLabel = uilabel(app.Keybot_InterfaceUIFigure);
            app.TypehereTextAreaLabel.Text = 'Type here:';
            app.TypehereTextArea = uitextarea(app.Keybot_InterfaceUIFigure);
            app.TypehereTextArea.ValueChangedFcn = createCallbackFcn(app, @TypehereTextAreaValueChanged, true);
            app.TypehereTextArea.ValueChangingFcn = createCallbackFcn(app, @TypehereTextAreaValueChanging, true);
            app.TexttotypeTextAreaLabel = uilabel(app.Keybot_InterfaceUIFigure);
            app.TexttotypeTextAreaLabel.Text = 'Text to type';
            app.TexttotypeTextArea = uitextarea(app.Keybot_InterfaceUIFigure);
            app.TexttotypeTextArea.Value = {'the quick brown fox jumps over the lazy dog. the quick brown fox jumps over the lazy dog. wow.'};
            app.TimeLeftsecEditFieldLabel = uilabel(app.Keybot_InterfaceUIFigure);
            app.TimeLeftsecEditFieldLabel.Text = 'Time Left (sec):';
            app.TimeLeftsecEditField = uieditfield(app.Keybot_InterfaceUIFigure, 'text');
            app.TimeLeftsecEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLeftsecEditFieldValueChanged, true);
            app.TimeLeftsecEditField.Value = '40';
            app.EndButton = uibutton(app.Keybot_InterfaceUIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.LetterSentTextAreaLabel = uilabel(app.Keybot_InterfaceUIFigure);
            app.LetterSentTextAreaLabel.Text = 'Letter Sent:';
            app.LetterSentTextArea = uitextarea(app.Keybot_InterfaceUIFigure);
            app.LetterSentTextArea.Value = {'&'};
            % Show the figure after all components are created
            app.Keybot_InterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KeybotInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Keybot_InterfaceUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Keybot_InterfaceUIFigure)
        end
    end
end